classdef ConductanceVsT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConductancevsTemperatureUIFigure  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        SameTemperatureMenu       matlab.ui.container.Menu
        SameMagneticFieldMenu     matlab.ui.container.Menu
        PlotCurrentButton         matlab.ui.control.Button
        ParametersGroup           matlab.ui.container.ButtonGroup
        MagneticFieldButton       matlab.ui.control.RadioButton
        TemperatureButton         matlab.ui.control.RadioButton
        ClearButton               matlab.ui.control.Button
        SeparationEditField       matlab.ui.control.EditField
        SeparationEditFieldLabel  matlab.ui.control.Label
        VisualizationGroup        matlab.ui.container.ButtonGroup
        OffsetplotButton          matlab.ui.control.RadioButton
        DplotButton               matlab.ui.control.RadioButton
        OffsetmVEditField         matlab.ui.control.EditField
        OffsetmVEditFieldLabel    matlab.ui.control.Label
        Plot2ndDerivButton        matlab.ui.control.Button
        PlotConductanceButton     matlab.ui.control.Button
        PlotDOSButton             matlab.ui.control.Button
        CalculateButton           matlab.ui.control.Button
        mVLabel                   matlab.ui.control.Label
        andEditField              matlab.ui.control.EditField
        andEditFieldLabel         matlab.ui.control.Label
        betweenEditField          matlab.ui.control.EditField
        betweenEditFieldLabel     matlab.ui.control.Label
        NormalizationGroup        matlab.ui.container.ButtonGroup
        NotnormalizedButton       matlab.ui.control.RadioButton
        NormalizedButton          matlab.ui.control.RadioButton
        DerivativePointsSpinner   matlab.ui.control.Spinner
        DerivativePointsLabel     matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        AutoTab                   matlab.ui.container.Tab
        LoadfromdirectoryButton   matlab.ui.control.Button
        LoadfromWorkspaceButton   matlab.ui.control.Button
        ButtonGroup               matlab.ui.container.ButtonGroup
        BackButton                matlab.ui.control.RadioButton
        ForthButton               matlab.ui.control.RadioButton
        SelectPathButton          matlab.ui.control.Button
        ManualTab                 matlab.ui.container.Tab
        Label                     matlab.ui.control.Label
        AddFBDropDown             matlab.ui.control.DropDown
        BlqColumnSpinner          matlab.ui.control.Spinner
        ColumnSpinnerLabel        matlab.ui.control.Label
        PlotForthBackCheck        matlab.ui.control.CheckBox
        HTLabel                   matlab.ui.control.Label
        HTEditField               matlab.ui.control.EditField
        TempKLabel                matlab.ui.control.Label
        ExporttodirectoryButton   matlab.ui.control.Button
        NCurvLabel                matlab.ui.control.Label
        IndexSpinner              matlab.ui.control.Spinner
        SpinnerNCurv              matlab.ui.control.Spinner
        ExporttoWorkspaceButton   matlab.ui.control.Button
        DrawButton                matlab.ui.control.Button
        TempKEditField            matlab.ui.control.EditField
        AddCurveButton            matlab.ui.control.Button
        OpenBLQButton             matlab.ui.control.Button
        ConductancevsLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        ForthBack
        NCurv
        Temperature,MagneticField
%         FilePath,FileName
        PuntosDerivada
        Voltage,MatrizCorriente,MatrizConductancia,MatrizNormalizada,MatrizDOS,Matriz2Deriv
        VoltageOffset
        curvas
        VoltageCurva,CorrienteCurva,ConductanciaCurva
        OffsetFlag
        HTFlag
        FileName
        FilePath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions');
            addpath(genpath('Functions'));
            if app.ForthButton.Value
                app.ForthBack = 1;
            else
                app.ForthBack = 2;
            end
            
            app.PuntosDerivada = app.DerivativePointsSpinner.Value;
            app.OffsetFlag = 1;
            app.HTFlag = app.TemperatureButton.Value;
            app.FilePath = pwd;
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            app.OffsetFlag = 1;
            [app.Voltage,app.MatrizCorriente,app.Temperature,app.MagneticField,app.FilePath] = selectPath(app.ForthBack,'');
%             [app.FilePath] = uigetdir;
%             load([app.FilePath '/Temperature.mat']);
%             app.Temperature = Temperature;
        end

        % Button pushed function: OpenBLQButton
        function OpenBLQButtonPushed(app, event)
            [app.FileName,app.FilePath,app.curvas] = openBlq('');
            if ~isempty(app.curvas)
                app.IndexSpinner.Limits = [0, length(app.curvas)-1];
                if size(app.curvas(1).data,2) > 2
                    app.BlqColumnSpinner.Enable = true;
                    app.BlqColumnSpinner.Limits = [2,size(app.curvas(1).data,2)];
                else
                    app.BlqColumnSpinner.Enable = false;
                end
            end
        end

        % Selection changed function: NormalizationGroup
        function NormalizationGroupSelectionChanged(app, event)
%             selectedButton = app.NormalizationGroup.SelectedObject;
            if app.NormalizedButton.Value
                app.betweenEditField.Enable = 'On';
                app.andEditField.Enable = 'On';
            else
                app.betweenEditField.Enable = 'Off';
                app.andEditField.Enable = 'Off';
            end
        end

        % Value changed function: DerivativePointsSpinner
        function DerivativePointsSpinnerValueChanged(app, event)
            value = app.DerivativePointsSpinner.Value;
            app.PuntosDerivada = value;
            if length(app.curvas)>1
                DrawButtonPushed(app,event)
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        if app.OffsetFlag
            app.VoltageOffset = app.Voltage + str2double(app.OffsetmVEditField.Value)/1000;
        else
            app.VoltageOffset = app.Voltage;
        end
            [app.MatrizConductancia,app.MatrizDOS,app.Matriz2Deriv] =...
                calculate(app.VoltageOffset,app.MatrizCorriente,app.Temperature,...
                app.PuntosDerivada,str2double(app.betweenEditField.Value)/1000,...
                str2double(app.andEditField.Value)/1000,app.NormalizedButton.Value);
        end

        % Button pushed function: PlotDOSButton
        function PlotDOSButtonPushed(app, event)
        zlabel = '\fontsize{15} DOS';
        
        if app.HTFlag
            ylabel = '\fontsize{15} Temperature (K)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.Temperature,app.MatrizDOS,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.MatrizDOS,app.Temperature,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        else
            ylabel = '\fontsize{15} Magnetic Field (T)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.MagneticField,app.MatrizDOS,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.MatrizDOS,app.MagneticField,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        end       
        end

        % Button pushed function: PlotConductanceButton
        function PlotConductanceButtonPushed(app, event)
        if app.NormalizedButton.Value
            zlabel = '\fontsize{15} Normalized conductance (a.u.)';
        else
            zlabel = '\fontsize{15} Conductance (S)';
        end
        
        if app.HTFlag
            ylabel = '\fontsize{15} Temperature (K)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.Temperature,app.MatrizConductancia,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.MatrizConductancia,app.Temperature,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        else
            ylabel = '\fontsize{15} Magnetic field (T)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.MagneticField,app.MatrizConductancia,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.MatrizConductancia,app.MagneticField,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        end
        
        
        end

        % Button pushed function: Plot2ndDerivButton
        function Plot2ndDerivButtonPushed(app, event)
        zlabel = '\fontsize{15} dConductance/dV';
        
        if app.HTFlag
            ylabel = '\fontsize{15} Temperature (K)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.Temperature,app.Matriz2Deriv,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.Matriz2Deriv,app.Temperature,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        else
            ylabel = '\fontsize{15} Magnetic Field (T)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.MagneticField,app.Matriz2Deriv,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.Matriz2Deriv,app.MagneticField,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        end       
        end

        % Selection changed function: VisualizationGroup
        function VisualizationGroupSelectionChanged(app, event)
            if app.DplotButton.Value
                app.SeparationEditField.Enable = 'Off';                
            else
                app.SeparationEditField.Enable = 'On';
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            if app.PlotForthBackCheck.Value
            [app.VoltageCurva,app.CorrienteCurva,app.ConductanciaCurva] = ...
                drawForthBack(app.curvas,app.IndexSpinner.Value+1,app.DerivativePointsSpinner.Value,...
                str2double(app.betweenEditField.Value)/1000,str2double(app.andEditField.Value)/1000,...
                str2double(app.OffsetmVEditField.Value)/1000,app.NormalizedButton.Value,app.BlqColumnSpinner.Value,app.AddFBDropDown.Value);
            else
            [app.VoltageCurva,app.CorrienteCurva,app.ConductanciaCurva] = ...
                drawCurve(app.curvas,app.IndexSpinner.Value+1,app.DerivativePointsSpinner.Value,...
                str2double(app.betweenEditField.Value)/1000,str2double(app.andEditField.Value)/1000,...
                str2double(app.OffsetmVEditField.Value)/1000,app.NormalizedButton.Value,app.BlqColumnSpinner.Value);
            end
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
            if length(app.curvas)>1
                DrawButtonPushed(app,event)
            end
        end

        % Value changed function: BlqColumnSpinner
        function BlqColumnSpinnerValueChanged(app, event)
            if length(app.curvas)>1
                DrawButtonPushed(app,event)
            end
        end

        % Value changed function: PlotForthBackCheck
        function PlotForthBackCheckValueChanged(app, event)
            if app.PlotForthBackCheck.Value
                app.IndexSpinner.Value = 0;
                app.IndexSpinner.Step = 2;
                if length(app.curvas) > 2 %multiple IVs in the same blq
                    app.IndexSpinner.Enable = true;
                    app.IndexSpinner.Limits = [0, length(app.curvas)-2];
                else
                    app.IndexSpinner.Enable = false;
                end
                app.AddFBDropDown.Enable = true;
            else
                app.IndexSpinner.Value = 0;
                app.IndexSpinner.Step = 1;
                app.IndexSpinner.Limits = [0, length(app.curvas)-1];
                app.IndexSpinner.Enable = true;
                app.AddFBDropDown.Enable = false;
            end
    
            if length(app.curvas)>1
                DrawButtonPushed(app,event)
            end
        end

        % Button pushed function: AddCurveButton
        function AddCurveButtonPushed(app, event)
            switch app.AddFBDropDown.Value
                case 'Both'
                    app.OffsetFlag = 0;
                    [app.Voltage,app.MatrizCorriente,app.Temperature,app.MagneticField] =...
                        addCurve(app.Voltage,app.VoltageCurva,app.MatrizCorriente,...
                        app.CorrienteCurva,app.Temperature,app.MagneticField,...
                        str2double(app.TempKEditField.Value)*ones(2,1),str2double(app.HTEditField.Value)*ones(2,1),...
                        [app.SpinnerNCurv.Value,app.SpinnerNCurv.Value+1]);
                    A = size(app.Voltage);
                    app.SpinnerNCurv.Limits(2) = A(2)+1;
                    app.SpinnerNCurv.Value = A(2)+1;
                    app.SpinnerNCurv.Limits(1) = 1;
                otherwise
                    app.OffsetFlag = 0;
                    [app.Voltage,app.MatrizCorriente,app.Temperature,app.MagneticField] =...
                        addCurve(app.Voltage,app.VoltageCurva,app.MatrizCorriente,...
                        app.CorrienteCurva,app.Temperature,app.MagneticField,...
                        str2double(app.TempKEditField.Value),str2double(app.HTEditField.Value),...
                        app.SpinnerNCurv.Value);

                    A = size(app.Voltage);
                    app.SpinnerNCurv.Limits(2) = A(2)+1;
                    app.SpinnerNCurv.Value = A(2)+1;
                    app.SpinnerNCurv.Limits(1) = 1;
            end
        end

        % Value changed function: AddFBDropDown
        function AddFBDropDownValueChanged(app, event)
            if length(app.curvas)>1
                DrawButtonPushed(app,event)
            end            
        end

        % Button pushed function: LoadfromWorkspaceButton
        function LoadfromWorkspaceButtonPushed(app, event)
            app.OffsetFlag = 1;
            Struct = importWSVariable('Struct');
            if isempty(Struct)
                warning('No data available in the workspace')
            else
                if isfield(Struct, "Voltage") && ...
                    isfield(Struct, "MatrizCorriente") && ...
                    isfield(Struct, "Temperature") && ...
                    isfield(Struct, "MagneticField")
                    
                    [app.Voltage] = Struct.Voltage;
                    [app.MatrizCorriente] = Struct.MatrizCorriente;
                    [app.Temperature] = Struct.Temperature;
                    [app.MagneticField] = Struct.MagneticField;
                else
                    errordlg("Struct fields missing. " + ...
                        "Check the console for more information","Missing Fields")
                    error("The provided struct does not match the expected form. " + ...
                        "The following fields are expected: Voltage, " + ...
                        "MatrizCorriente, Temperature and Magnetic field")
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            [app.Voltage,app.MatrizCorriente,app.MatrizConductancia,app.MatrizNormalizada,...
                app.MatrizDOS,app.Matriz2Deriv,app.Temperature,app.VoltageOffset,...
                app.VoltageCurva,app.CorrienteCurva,app.ConductanciaCurva] =...
                resetVariables();
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            disp(isempty(app.MatrizCorriente))
            if ~isempty(app.MatrizCorriente)
            Struct.Temperature = app.Temperature;
            Struct.MagneticField = app.MagneticField;
            Struct.VoltageOffset = app.VoltageOffset;
            Struct.Voltage = app.Voltage;      
            Struct.MatrizCorriente = app.MatrizCorriente;
            Struct.MatrizConductancia = app.MatrizConductancia;
            Struct.MatrizDOS = app.MatrizDOS;
            Struct.Matriz2Deriv = app.Matriz2Deriv;
                exportWSVariable(Struct)
            else,  msgbox('No data has been loaded yet',"Slower, cowboy!",'error'), end
        end

        % Button pushed function: ExporttodirectoryButton
        function ExporttodirectoryButtonPushed(app, event)
            if ~isempty(app.MatrizCorriente)
            [Name,Path,~] = uiputfile('*.mat');
                if ~isequal(Name,0)
                Struct.Temperature = app.Temperature;
                Struct.MagneticField = app.MagneticField;
                Struct.VoltageOffset = app.VoltageOffset;
                Struct.Voltage = app.Voltage;
                Struct.MatrizCorriente = app.MatrizCorriente;
                Struct.MatrizConductancia = app.MatrizConductancia;
                Struct.MatrizDOS = app.MatrizDOS;
                Struct.Matriz2Deriv = app.Matriz2Deriv;
            
                save([Path Name],'Struct');
        %             exportVariable(Struct,FilePath) 
                end
            else,  msgbox('No data available to export',"Slower, cowboy!",'error'), end
        end

        % Button pushed function: LoadfromdirectoryButton
        function LoadfromdirectoryButtonPushed(app, event)
            Struct = importVariable('Load Struct');
            if isempty(Struct) %Check for empty output
                disp("Data loading was aborted")
            else
                %Check for the necessary fields before the assignment
                if isfield(Struct, "Voltage") && ...
                    isfield(Struct, "MatrizCorriente") && ...
                    isfield(Struct, "Temperature") && ...
                    isfield(Struct, "MagneticField")

                    [app.Voltage] = Struct.Voltage;
                    [app.MatrizCorriente] = Struct.MatrizCorriente;
                    [app.Temperature] = Struct.Temperature;
                    [app.MagneticField] = Struct.MagneticField;
                else
                    errordlg("Struct fields missing. " + ...
                        "Check the console for more information","Missing Fields")
                    error("The provided struct does not match the expected form. " + ...
                        "The following fields are expected: Voltage, " + ...
                        "MatrizCorriente, Temperature and Magnetic field")
                end
            end
        end

        % Selection changed function: ParametersGroup
        function ParametersGroupSelectionChanged(app, event)
            if app.TemperatureButton.Value
%                 app.TempKLabel.Text = 'Temp. (K)';
%                 app.PlotDOSButton.Enable = 1;
                app.HTFlag = 1;
%                 app.TempKEditField.Tooltip = 'Temperature for this curve';
            else
%                 app.TempKLabel.Text = 'H (T)';
%                 app.PlotDOSButton.Enable = 0;
                app.HTFlag = 0;
%                 app.TempKEditField.Tooltip = 'Magnetic field for this curve';
            end
            
        end

        % Menu selected function: SameTemperatureMenu
        function SameTemperatureMenuSelected(app, event)
            A = size(app.MatrizCorriente);
            if A(2)>0
                app.Temperature = str2double(app.TempKEditField.Value)*ones(A(2),1);                
            end
        end

        % Menu selected function: SameMagneticFieldMenu
        function SameMagneticFieldMenuSelected(app, event)
            A = size(app.MatrizCorriente);
            if A(2)>0
                app.MagneticField = str2double(app.HTEditField.Value)*ones(A(2),1);                
            end
        end

        % Button pushed function: PlotCurrentButton
        function PlotCurrentButtonPushed(app, event)
        zlabel = '\fontsize{15} Current (A)';
       
        if app.HTFlag
            ylabel = '\fontsize{15} Temperature (K)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.Temperature,app.MatrizCorriente,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.MatrizCorriente,app.Temperature,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        else
            ylabel = '\fontsize{15} Magnetic field (T)';
            if app.DplotButton.Value
                plotCurves(app.VoltageOffset,app.MagneticField,app.MatrizCorriente,ylabel,zlabel)
            else
                plotCurvesOffset(app.VoltageOffset,app.MatrizCorriente,app.MagneticField,str2double(app.SeparationEditField.Value),zlabel,app.HTFlag)
            end
        end
        end

        % Close request function: ConductancevsTemperatureUIFigure
        function ConductancevsTemperatureUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConductancevsTemperatureUIFigure = uifigure('Visible', 'off');
            app.ConductancevsTemperatureUIFigure.Name = 'Conductance vs Temperature';
            app.ConductancevsTemperatureUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConductancevsTemperatureUIFigureCloseRequest, true);
            app.Menu = uimenu(app.ConductancevsTemperatureUIFigure);
            app.Menu.Text = 'Menu';
            app.SameTemperatureMenu = uimenu(app.Menu);
            app.SameTemperatureMenu.MenuSelectedFcn = createCallbackFcn(app, @SameTemperatureMenuSelected, true);
            app.SameTemperatureMenu.Tooltip = {'Assign the same temperature [Temp.(K)] to all curves'};
            app.SameTemperatureMenu.Text = 'Same Temperature';
            app.SameMagneticFieldMenu = uimenu(app.Menu);
            app.SameMagneticFieldMenu.MenuSelectedFcn = createCallbackFcn(app, @SameMagneticFieldMenuSelected, true);
            app.SameMagneticFieldMenu.Tooltip = {'Assign the same magnetic field[H(T)] to all curves'};
            app.SameMagneticFieldMenu.Text = 'Same Magnetic Field';
            app.ConductancevsLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.ConductancevsLabel.Text = 'Conductance vs ';
            app.TabGroup = uitabgroup(app.ConductancevsTemperatureUIFigure);
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.SelectPathButton = uibutton(app.AutoTab, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Tooltip = {'Select path for automatic blq open:'; '- blq files named from ''1.blq'' to ''n.blq'''; '- blq files only containing one curve each (forth and back)'; '- It asks for a ''Temperature.mat'' vector of temperatures and a ''MagneticField.mat'' vector of magnetic fields'};
            app.SelectPathButton.Text = 'Select Path';
            app.ButtonGroup = uibuttongroup(app.AutoTab);
            app.ForthButton = uiradiobutton(app.ButtonGroup);
            app.ForthButton.Text = 'Forth';
            app.ForthButton.Value = true;
            app.BackButton = uiradiobutton(app.ButtonGroup);
            app.BackButton.Text = 'Back';
            app.LoadfromWorkspaceButton = uibutton(app.AutoTab, 'push');
            app.LoadfromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromWorkspaceButtonPushed, true);
            app.LoadfromWorkspaceButton.Tooltip = {'Import data from workspace variable ''Struct'' containing:'; '- ''Struct.Voltage'': voltage matrix (NPoints x NCurves)'; '- ''Struct.MatrizCorriente'': Intensity matrix (NPoints x NCurves)'; '-''Struct.Temperatrue'': temperature vector (NCurves x 1)'; '-''Struct.MagneticField'': magnetic field vector (NCurves x 1)'};
            app.LoadfromWorkspaceButton.Text = {'Load from'; 'Workspace'};
            app.LoadfromdirectoryButton = uibutton(app.AutoTab, 'push');
            app.LoadfromdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromdirectoryButtonPushed, true);
            app.LoadfromdirectoryButton.Tooltip = {'Import data from ''.mat'' file (struct) containing:'; '- ''Struct.Voltage'': voltage matrix (NPoints x NCurves)'; '- ''Struct.MatrizCorriente'': Intensity matrix (NPoints x NCurves)'; '-''Struct.Temperatrue'': temperature vector (NCurves x 1)'; '-''Struct.MagneticField'': magnetic field vector (NCurves x 1)'};
            app.LoadfromdirectoryButton.Text = {'Load from'; 'directory'};
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.OpenBLQButton = uibutton(app.ManualTab, 'push');
            app.OpenBLQButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBLQButtonPushed, true);
            app.OpenBLQButton.Tooltip = {'Select blq file'};
            app.OpenBLQButton.Text = {'Open'; 'BLQ'};
            app.AddCurveButton = uibutton(app.ManualTab, 'push');
            app.AddCurveButton.ButtonPushedFcn = createCallbackFcn(app, @AddCurveButtonPushed, true);
            app.AddCurveButton.Tooltip = {'Save current curve in current index'};
            app.AddCurveButton.Text = {'Add'; 'Curve'};
            app.TempKEditField = uieditfield(app.ManualTab, 'text');
            app.TempKEditField.Tooltip = {'Temperature for this curve'};
            app.TempKEditField.Value = '0.1';
            app.DrawButton = uibutton(app.ManualTab, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Tooltip = {'Plot Intensity vs Voltage curve and its derivative with the parameters given below'};
            app.DrawButton.Text = 'Draw';
            app.ExporttoWorkspaceButton = uibutton(app.ManualTab, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Tooltip = {'Export data to workspace'};
            app.ExporttoWorkspaceButton.Text = {'Export to'; 'Workspace'};
            app.SpinnerNCurv = uispinner(app.ManualTab);
            app.SpinnerNCurv.Limits = [0 1];
            app.SpinnerNCurv.Tooltip = {'Index of the curve to save'};
            app.SpinnerNCurv.Value = 1;
            app.IndexSpinner = uispinner(app.ManualTab);
            app.IndexSpinner.Limits = [0 Inf];
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.IndexSpinner.Tooltip = {'Index of the curve to show inside the blq file.'};
            app.NCurvLabel = uilabel(app.ManualTab);
            app.NCurvLabel.Text = 'NCurv';
            app.ExporttodirectoryButton = uibutton(app.ManualTab, 'push');
            app.ExporttodirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttodirectoryButtonPushed, true);
            app.ExporttodirectoryButton.Tooltip = {'Export data to file'};
            app.ExporttodirectoryButton.Text = {'Export to'; 'directory'};
            app.TempKLabel = uilabel(app.ManualTab);
            app.TempKLabel.Text = 'Temp. (K)';
            app.HTEditField = uieditfield(app.ManualTab, 'text');
            app.HTEditField.Tooltip = {'Magnetic Field for this curve'};
            app.HTEditField.Value = '0';
            app.HTLabel = uilabel(app.ManualTab);
            app.HTLabel.Text = 'H (T)';
            app.PlotForthBackCheck = uicheckbox(app.ManualTab);
            app.PlotForthBackCheck.ValueChangedFcn = createCallbackFcn(app, @PlotForthBackCheckValueChanged, true);
            app.PlotForthBackCheck.Text = 'Forth & Back';
            app.ColumnSpinnerLabel = uilabel(app.ManualTab);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.BlqColumnSpinner = uispinner(app.ManualTab);
            app.BlqColumnSpinner.Limits = [2 Inf];
            app.BlqColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @BlqColumnSpinnerValueChanged, true);
            app.BlqColumnSpinner.Enable = 'off';
            app.BlqColumnSpinner.Value = 2;
            app.AddFBDropDown = uidropdown(app.ManualTab);
            app.AddFBDropDown.Items = {'Forth', 'Back', 'Both', 'Avg'};
            app.AddFBDropDown.ValueChangedFcn = createCallbackFcn(app, @AddFBDropDownValueChanged, true);
            app.AddFBDropDown.Enable = 'off';
            app.AddFBDropDown.Tooltip = {'Choose what to add when working with Forth/Back pairs'};
            app.AddFBDropDown.Value = 'Forth';
            app.Label = uilabel(app.ManualTab);
            app.Label.Text = 'Add';
            app.DerivativePointsLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.DerivativePointsLabel.Text = {'Derivative'; 'Points'};
            app.DerivativePointsSpinner = uispinner(app.ConductancevsTemperatureUIFigure);
            app.DerivativePointsSpinner.Limits = [1 Inf];
            app.DerivativePointsSpinner.RoundFractionalValues = 'on';
            app.DerivativePointsSpinner.ValueChangedFcn = createCallbackFcn(app, @DerivativePointsSpinnerValueChanged, true);
            app.DerivativePointsSpinner.Value = 32;
            app.NormalizationGroup = uibuttongroup(app.ConductancevsTemperatureUIFigure);
            app.NormalizationGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizationGroupSelectionChanged, true);
            app.NormalizedButton = uiradiobutton(app.NormalizationGroup);
            app.NormalizedButton.Text = 'Normalized';
            app.NotnormalizedButton = uiradiobutton(app.NormalizationGroup);
            app.NotnormalizedButton.Text = 'Not normalized';
            app.NotnormalizedButton.Value = true;
            app.betweenEditFieldLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.betweenEditFieldLabel.Text = 'between';
            app.betweenEditField = uieditfield(app.ConductancevsTemperatureUIFigure, 'text');
            app.betweenEditField.Value = '7.5';
            app.andEditFieldLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.andEditFieldLabel.Text = 'and';
            app.andEditField = uieditfield(app.ConductancevsTemperatureUIFigure, 'text');
            app.andEditField.Value = '8';
            app.mVLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.mVLabel.Text = 'mV';
            app.CalculateButton = uibutton(app.ConductancevsTemperatureUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tooltip = {'Calculate conductance'; ' 2nd derivative and DOS with the given parameters'};
            app.CalculateButton.Text = 'Calculate';
            app.PlotDOSButton = uibutton(app.ConductancevsTemperatureUIFigure, 'push');
            app.PlotDOSButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDOSButtonPushed, true);
            app.PlotDOSButton.Text = 'Plot DOS';
            app.PlotConductanceButton = uibutton(app.ConductancevsTemperatureUIFigure, 'push');
            app.PlotConductanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotConductanceButtonPushed, true);
            app.PlotConductanceButton.Text = 'Plot Conductance';
            app.Plot2ndDerivButton = uibutton(app.ConductancevsTemperatureUIFigure, 'push');
            app.Plot2ndDerivButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2ndDerivButtonPushed, true);
            app.Plot2ndDerivButton.Text = 'Plot 2nd Deriv.';
            app.OffsetmVEditFieldLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.OffsetmVEditFieldLabel.Text = 'Offset (mV)';
            app.OffsetmVEditField = uieditfield(app.ConductancevsTemperatureUIFigure, 'text');
            app.OffsetmVEditField.Value = '0';
            app.VisualizationGroup = uibuttongroup(app.ConductancevsTemperatureUIFigure);
            app.VisualizationGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationGroupSelectionChanged, true);
            app.DplotButton = uiradiobutton(app.VisualizationGroup);
            app.DplotButton.Text = '3D plot';
            app.DplotButton.Value = true;
            app.OffsetplotButton = uiradiobutton(app.VisualizationGroup);
            app.OffsetplotButton.Text = 'Offset plot';
            app.SeparationEditFieldLabel = uilabel(app.ConductancevsTemperatureUIFigure);
            app.SeparationEditFieldLabel.Text = 'Separation';
            app.SeparationEditField = uieditfield(app.ConductancevsTemperatureUIFigure, 'text');
            app.SeparationEditField.Enable = 'off';
            app.SeparationEditField.Tooltip = {'Separation between consecutive curves for offset plot'};
            app.SeparationEditField.Value = '0.1';
            app.ClearButton = uibutton(app.ConductancevsTemperatureUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Tooltip = {'Clear all. Reset all variables.'};
            app.ClearButton.Text = 'X';
            app.ParametersGroup = uibuttongroup(app.ConductancevsTemperatureUIFigure);
            app.ParametersGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametersGroupSelectionChanged, true);
            app.ParametersGroup.Tooltip = {'No need for explanation'; ' come on...'};
            app.TemperatureButton = uiradiobutton(app.ParametersGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.TemperatureButton.Value = true;
            app.MagneticFieldButton = uiradiobutton(app.ParametersGroup);
            app.MagneticFieldButton.Text = 'Magnetic Field';
            app.PlotCurrentButton = uibutton(app.ConductancevsTemperatureUIFigure, 'push');
            app.PlotCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCurrentButtonPushed, true);
            app.PlotCurrentButton.Text = 'Plot Current';
            % Show the figure after all components are created
            app.ConductancevsTemperatureUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConductanceVsT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConductancevsTemperatureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConductancevsTemperatureUIFigure)
        end
    end
end