classdef DeconvoluteGap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        MirrorButton                    matlab.ui.control.Button
        SmoothButton                    matlab.ui.control.Button
        BLabel                          matlab.ui.control.Label
        SeparationEditField             matlab.ui.control.NumericEditField
        FindPeaksButton                 matlab.ui.control.Button
        TLabel                          matlab.ui.control.Label
        OffsetPlotButton                matlab.ui.control.Button
        CurveSpinner                    matlab.ui.control.Spinner
        SaveButton                      matlab.ui.control.Button
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        StretchInferiorFactorEditField  matlab.ui.control.NumericEditField
        StretchInferiorFactorEditFieldLabel  matlab.ui.control.Label
        StretchSuperiorFactorEditField  matlab.ui.control.NumericEditField
        StretchSuperiorFactorEditFieldLabel  matlab.ui.control.Label
        ShrinkFactorEditField           matlab.ui.control.NumericEditField
        ShrinkFactorEditFieldLabel      matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        LoadStructButton                matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Temperature     % Array with Temperature values for each curve
        MagneticField   % Array with Magnetic field values
        Voltage         % Voltage values of conductance curves
        Conductance     % Conductance curves

        dFermiDist      % Derivative of Fermi distribution
        DOS             % Density of States from each curve
        Convolution     % Result of convoluting Fermi dist with DOS (fit)
     VoltageConvolution % Voltage(x axis) of the convolution
     DOSSmooth          % DOS smoothed after deconvolution
     ConductanceSmooth  % Conductance data smoothed

        HFactor         % Array with factors for shrink 
        VFactorSup      % Array with factors for above 1 stretch 
        VFactorInf      % Array with factors for below 1 stretch 
        Offset
        Gap             % Array with positions of peak of DOS

        DOSGuess        % Test for a DOS curve. Used for stretch and shrink

        Analisis        % Struct with result parameters of deconvoution

        axCond
        axDOS
        
        StructGap       % Struct with variables as fields
        StructLoad          % Loaded Struct

        SmoothApp       % Dialog box with smooth options
    end
    
    methods (Access = public)
        function formatSubplotConvDOS(app)
         axesPanel = findobj(app.Panel,'type','axes');

            set(axesPanel, 'XColor', [0 0 0], 'YColor', [0 0 0], ...
            'FontSize', 14, 'Fontname', 'Arial', 'FontWeight','bold',...
            'Box', 'on');

            % Name the axes
            set(app.axCond.XLabel,'String','Voltage (mV)');
            set(app.axCond.YLabel,'String','Normalized Conductance (a.u.)');
            set(app.axDOS.XLabel,'String','Energy (meV)');
            set(app.axDOS.YLabel,'String','DOS (a.u.)');

            % Move axis to the right
            set(app.axDOS,'YAxisLocation','right');
            % Rotate Y axis label
            %set(app.axDOS.YLabel,'Rotation',-90);
            %app.axDOS.YLabel.Position(1) = 1.4;


    end
        
    function [] = readAnalisisFields(app, Struct)
            if isfield(Struct,'HFactor')
                app.HFactor = Struct.HFactor;
            end
            if isfield(Struct,'VFactorSup')
                app.VFactorSup = Struct.VFactorSup;
            end
            if isfield(Struct,'VFactorInf')
                app.VFactorInf = Struct.VFactorInf;
            end
            if isfield(Struct,'Offset')
                app.Offset = Struct.Offset;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: LoadStructButton
        function LoadStructButtonPushed(app, event)
            % Select file to extract an Struct variable
            Struct = importVariable('Load Struct');
            
            % Extract fields from Struct
            if isfield(Struct, "Temperature")
                [app.Temperature] = Struct.Temperature;
            else
                error("There are no Temperature data in Struct")
            end

            if isfield(Struct, "MagneticField")
                [app.MagneticField] = Struct.MagneticField;
            else
                warning("There is no MagneticField field in Struct")
            end

            if isfield(Struct, "Voltage")
                [app.Voltage] = Struct.Voltage; 
                [app.Voltage] = app.Voltage * 1e3; % Use mV instead of V
            end

            if isfield(Struct, "MatrizConductancia")
                [app.Conductance] = Struct.MatrizConductancia;
                app.ConductanceSmooth = app.Conductance;
            else
                error("There is not MatrizConductancia field in Struct")
            end
            app.StructLoad = struct();
            app.StructLoad = Struct;
            

            % Take lower T Conductance as first guess of DOS
            [~,idxLowT] = min(app.Temperature);
            app.DOSGuess = app.Conductance(:,idxLowT);


            % Create plots
            tiles = tiledlayout(app.Panel, 1, 2, 'TileSpacing','tight');

            app.axCond = nexttile(tiles);
            app.axDOS = nexttile(tiles);

            % Prepare axes
            axesPanel = findobj(app.Panel,'type','axes');
            %set([app.axCond, app.DOS],'XLim', [min(app.Voltage,[],'all'), max(app.Voltage,[],'all')])

            % Initialize variables of fitting
            % Factors set to 1 by default
            [app.HFactor, app.VFactorSup, app.VFactorInf] = ...
                deal(ones(1,length(app.Temperature)));
            % Offset to to 0 by default
            app.Offset = zeros(1, length(app.Temperature));

            % Initialize gap with 0s
            app.Gap = zeros(1,length(app.Temperature));

            % Initialize DOS matrix with DOSGuess
            app.DOS = app.DOSGuess.*ones(1,length(app.Temperature));
            app.DOSSmooth = app.DOS;


       % Calculate dFermi distribution for each Temperature and save it
            [app.dFermiDist, app.Convolution, app.VoltageConvolution] ...
                = deal(zeros(size(app.Conductance)));

            app.dFermiDist = FermiDeriv(app.Temperature', app.Voltage);
            app.dFermiDist = app.dFermiDist./sum(app.dFermiDist);

            % If Struct had previous Analisis, load that.
            if isfield(Struct, 'Analisis')
                app.readAnalisisFields(Struct.Analisis);
            end
            

             % Enable edit fields and spinner
            set([app.CurveSpinner, app.StretchInferiorFactorEditField,...
                app.StretchSuperiorFactorEditField,...
                app.ShrinkFactorEditField, app.OffsetEditField],'Enable','on');
            

            % Initialize Spinner to 1 and select first curve.
            app.CurveSpinner.Limits = [1, length(app.Temperature)];
            app.CurveSpinner.Value = 1;
            app.CurveSpinnerValueChanged;

            % Make temperature and field visible
            app.TLabel.Visible = 1;
            app.BLabel.Visible = 1;
            % Create Plot with format
            app.formatSubplotConvDOS;

            if isfield(Struct, "HFactor")
                [app.HFactor] = Struct.HFactor;
            end
            if isfield(Struct, "VFactorSup")
                [app.VFactorSup] = Struct.VFactorSup;
            end
            if isfield(Struct,"VFactorInf")
                [app.VFactorInf] = Struct.VFactorInf;
            end


        end

        % Value changed function: CurveSpinner
        function CurveSpinnerValueChanged(app, event)
            % Use Spinner value as index to select the curve
            idx = app.CurveSpinner.Value;

            % Update EditFields with predefined factors
            [app.ShrinkFactorEditField.Value,...
             app.StretchSuperiorFactorEditField.Value,...
             app.StretchInferiorFactorEditField.Value,...
             app.OffsetEditField.Value] = deal(app.HFactor(idx),...
                        app.VFactorSup(idx), app.VFactorInf(idx),...
                        app.Offset(idx));


            % Update text with Temperature
            app.TLabel.Text = ["T = " + num2str(app.Temperature(idx)) + " K"];
            % Upadate text with field
            app.BLabel.Text = ["B = " + num2str(app.MagneticField(idx)) + " T"];
            % PLOT
            app.PlotButtonPushed;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

                % Save results and parameters of analysis
                app.Analisis.MatrizDOS = app.DOS;
                app.Analisis.VoltageConvolution = app.VoltageConvolution;
                app.Analisis.MatrizConvolution = app.Convolution;
                app.Analisis.dFermiDist = app.dFermiDist;
                app.Analisis.Gap = app.Gap;

                app.Analisis.HFactor = app.HFactor;
                app.Analisis.VFactorSup = app.VFactorSup;
                app.Analisis.VFactorInf = app.VFactorInf;
                app.Analisis.Offset = app.Offset;

                app.StructLoad.Analisis = app.Analisis;

                StructGapAnalisis = app.StructLoad;
                exportWSVariable(StructGapAnalisis)

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            idx = app.CurveSpinner.Value;

            % Convolute curves and save result and DOS used
            [ConvCond, DOSresult] = convolutionFermi(app.dFermiDist(:,idx),...
                            app.DOSGuess, app.HFactor(idx),...
                            app.VFactorSup(idx), app.VFactorInf(idx));

            % Correct any possible offset
            ConvVoltage = app.Voltage(:,idx) - app.Offset(idx);

            % Save DOS
            app.DOS(:,idx) = DOSresult;
            % Save Convolution
            app.Convolution(:,idx) = ConvCond;
            % Save offset Voltage for convolution
            app.VoltageConvolution(:,idx) = ConvVoltage;
         
            % Clear axes
            % Find and delete Line graphs from Axes    
            delete(findobj(app.Panel, 'Type', 'line'));

            % Plot in subplot left - Conductance

            plot(app.axCond, app.Voltage(:,idx), app.Conductance(:,idx),...
                'k','LineWidth',2,'DisplayName','Data');
            hold(app.axCond,"on")

            plot(app.axCond,ConvVoltage,ConvCond,...
                '-', 'Color', [0.85 0 0],'LineWidth',2,...
                'DisplayName', 'Convolution');

            % Plot in subplot right - DOS
            plot(app.axDOS, app.Voltage(:,idx), DOSresult, 'm-')
            hold(app.axDOS,"on")
            % Plot original DOS to compare
            plot(app.axDOS, app.Voltage(:,idx), app.DOSGuess, 'b:')


        end

        % Button pushed function: OffsetPlotButton
        function OffsetPlotButtonPushed(app, event)
        separation = app.SeparationEditField.Value;
        txt = 'Normalized Conductance (shifted)';
        % Plot all curves shifted vertically in new figure
        % CAUTION - function plotCurves Offset uses Voltage in V
        plotCurvesOffset(app.Voltage/1e3, app.Conductance, app.Temperature,separation,txt, true)
        % Plot convolution over data
        plotCurvesOffsetOver(app.Voltage/1e3, app.Convolution, app.Temperature, separation, [], true, app.Offset)

        extAxCond = gca;
        txt = 'DOS (shifted)';
        plotCurvesOffset(app.Voltage/1e3, app.DOS, app.Temperature,separation,txt, true)
        % Take this second figure and displace it to the right
        figDOS = gcf;        
        figDOS.Position(1) = figDOS.Position(1)+figDOS.Position(3);

        hold off
        % Fit YAxis to DOS Data and share it with Conductance plot
        extAxDOS = gca;
        extAxDOS.YLim = [0 max([extAxDOS.Children.YData],[],'all') *1.01];
        extAxCond.YLim = extAxDOS.YLim;
        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            numCurves = length(app.Temperature);
            minProminence = 0.5; % Set how relatively high the peaks are

            % Find peaks of each DOS, in decreasing altitude. Use absolute
            % value in case highest peak negative, since function does not
            % search for those.
            for i = 1:numCurves
                [~,locpeaks] = findpeaks(flipud(abs(app.DOS(:,i))),...
                    flipud(app.Voltage(:,i)),...
                    'MinPeakProminence',minProminence,'SortStr','descend');
             
                 if length(locpeaks)>1
                     app.Gap(i) = abs(locpeaks(1)); % Take highest peak
%                    app.Gap(i) = mean(abs(locpeaks(1:2)));
                 end
            end

            % Plot peak position/gap vs Temperature
            fig = figure('Name', 'Gap vs T','NumberTitle','off');
            plot(app.Temperature, app.Gap, 'ok','MarkerSize',15,'LineWidth',2)

            fig.Children.YLim = [0, max(app.Gap)*1.1];
            fig.Children.XLim = [0, ceil(max(app.Temperature))];
            xlabel('Temperature (K)')
            ylabel('\Delta (meV)')
            fig.Children.LineWidth = 2;
            fig.Children.FontWeight = 'bold';
            fig.Children.FontName = 'Arial';
            fig.Children.FontSize = 14;
            fig.Children.XColor = [0 0 0];
            fig.Children.YColor = [0 0 0];
            fig.Children.Box = 'on';
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            value = app.CurveSpinner.Value;
            if verticalScrollCount>0 && value~=app.CurveSpinner.Limits(1)
                    app.CurveSpinner.Value = value - verticalScrollCount;
                    app.CurveSpinnerValueChanged;
            elseif verticalScrollCount<0 && value~=app.CurveSpinner.Limits(2)
                    app.CurveSpinner.Value = value - verticalScrollCount;    
                    app.CurveSpinnerValueChanged;
            end

        end

        % Value changed function: ShrinkFactorEditField
        function ShrinkFactorEditFieldValueChanged(app, event)
            idx = app.CurveSpinner.Value;
            % Save new HFactor in array at index selected
            app.HFactor(idx) = app.ShrinkFactorEditField.Value;
            app.PlotButtonPushed;
        end

        % Value changed function: StretchSuperiorFactorEditField
        function StretchSuperiorFactorEditFieldValueChanged(app, event)
            idx = app.CurveSpinner.Value;
            % Save new VFactorSup in array at index selected
            app.VFactorSup(idx) = app.StretchSuperiorFactorEditField.Value;
            app.PlotButtonPushed;
        end

        % Value changed function: StretchInferiorFactorEditField
        function StretchInferiorFactorEditFieldValueChanged(app, event)
            idx = app.CurveSpinner.Value;
            % Save new VFactorInf in array at index selected
            app.VFactorInf(idx) = app.StretchInferiorFactorEditField.Value;
            app.PlotButtonPushed;
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            idx = app.CurveSpinner.Value;
            % Save new Offset in array at index selected
            app.Offset(idx) = app.OffsetEditField.Value;
            app.PlotButtonPushed;
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            idx = app.CurveSpinner.Value;
            % Use smoothDOS or sth.
            if ~isfield(app.StructLoad,'DOSSmooth')
                app.StructLoad.DOSSmooth = app.DOSSmooth;
                app.StructLoad.ConductanceSmooth = app.ConductanceSmooth;
            end
            app.SmoothApp = SmoothDialogBox(app);

            app.SmoothButton.Enable = 'off';
            
            
        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            DOSMirror = app.DOSGuess;
            % Find minima to use as axis of mirror
            [~,minIdx] = min(DOSMirror);
            
            NPoints = size(DOSMirror,1);
            mirrorEnd = min(NPoints-minIdx, minIdx-1);

        % Make curve symmetrical around minima point
            for i=1:mirrorEnd
                DOSMirror(minIdx+i) = app.DOSGuess(minIdx-i);
            end
        % Replace DOS with mirrored one
            app.DOSGuess = DOSMirror;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {555, 555};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadStructButton = uibutton(app.LeftPanel, 'push');
            app.LoadStructButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStructButtonPushed, true);
            app.LoadStructButton.Tooltip = {'Load an Struct Variable with fields:'; '- Temperature'; '- VoltajeOffset'; '- MatrizConductancia'};
            app.LoadStructButton.Text = 'Load Struct';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Visible = 'off';
            app.PlotButton.Text = 'Plot';
            app.ShrinkFactorEditFieldLabel = uilabel(app.LeftPanel);
            app.ShrinkFactorEditFieldLabel.Text = 'Shrink Factor';
            app.ShrinkFactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ShrinkFactorEditField.Limits = [0 Inf];
            app.ShrinkFactorEditField.ValueChangedFcn = createCallbackFcn(app, @ShrinkFactorEditFieldValueChanged, true);
            app.ShrinkFactorEditField.Enable = 'off';
            app.ShrinkFactorEditField.Tooltip = {'Reduce value to shrink DOS'};
            app.ShrinkFactorEditField.Value = 1;
            app.StretchSuperiorFactorEditFieldLabel = uilabel(app.LeftPanel);
            app.StretchSuperiorFactorEditFieldLabel.Text = 'Stretch Superior Factor';
            app.StretchSuperiorFactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StretchSuperiorFactorEditField.Limits = [0 Inf];
            app.StretchSuperiorFactorEditField.ValueChangedFcn = createCallbackFcn(app, @StretchSuperiorFactorEditFieldValueChanged, true);
            app.StretchSuperiorFactorEditField.Enable = 'off';
            app.StretchSuperiorFactorEditField.Tooltip = {'Decrease value to reduce peak height'};
            app.StretchSuperiorFactorEditField.Value = 1;
            app.StretchInferiorFactorEditFieldLabel = uilabel(app.LeftPanel);
            app.StretchInferiorFactorEditFieldLabel.Text = 'Stretch Inferior Factor';
            app.StretchInferiorFactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StretchInferiorFactorEditField.Limits = [0 Inf];
            app.StretchInferiorFactorEditField.ValueChangedFcn = createCallbackFcn(app, @StretchInferiorFactorEditFieldValueChanged, true);
            app.StretchInferiorFactorEditField.Enable = 'off';
            app.StretchInferiorFactorEditField.Tooltip = {'Decrease value to reduce depth'};
            app.StretchInferiorFactorEditField.Value = 1;
            app.OffsetEditFieldLabel = uilabel(app.LeftPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OffsetEditField.ValueDisplayFormat = '%11.6g';
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.OffsetEditField.Enable = 'off';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CurveSpinner = uispinner(app.LeftPanel);
            app.CurveSpinner.Limits = [1 Inf];
            app.CurveSpinner.ValueDisplayFormat = '%.0f';
            app.CurveSpinner.ValueChangedFcn = createCallbackFcn(app, @CurveSpinnerValueChanged, true);
            app.CurveSpinner.Enable = 'off';
            app.CurveSpinner.Tooltip = {'Index of the curve inside the Struct.'};
            app.CurveSpinner.Value = 1;
            app.OffsetPlotButton = uibutton(app.LeftPanel, 'push');
            app.OffsetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetPlotButtonPushed, true);
            app.OffsetPlotButton.Text = 'Offset Plot';
            app.TLabel = uilabel(app.LeftPanel);
            app.TLabel.Visible = 'off';
            app.TLabel.Text = 'T = ';
            app.FindPeaksButton = uibutton(app.LeftPanel, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Text = 'Find Peaks';
            app.SeparationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SeparationEditField.Value = 0.1;
            app.BLabel = uilabel(app.LeftPanel);
            app.BLabel.Visible = 'off';
            app.BLabel.Text = 'B = ';
            app.SmoothButton = uibutton(app.LeftPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.MirrorButton = uibutton(app.LeftPanel, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Text = 'Mirror';
            app.RightPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeconvoluteGap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end