classdef SmoothDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ButtonGroup                 matlab.ui.container.ButtonGroup
        DOSButton                   matlab.ui.control.ToggleButton
        CondButton                  matlab.ui.control.ToggleButton
        IterationsEditField         matlab.ui.control.NumericEditField
        IterationsEditFieldLabel    matlab.ui.control.Label
        WindowEditField             matlab.ui.control.NumericEditField
        WindowEditFieldLabel        matlab.ui.control.Label
        ThresholdmeVEditField       matlab.ui.control.NumericEditField
        ThresholdmeVEditFieldLabel  matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % Main App
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)

            % Take values as inputs
            Threshold = app.ThresholdmeVEditField.Value;
            Window = app.WindowEditField.Value;
            Iterations = app.IterationsEditField.Value;
            % Take index of curve
            idx = app.CallingApp.CurveSpinner.Value;
            % Select what you want to smooth
            if app.DOSButton.Value
                curve = app.CallingApp.DOS(:,idx);
            else
                curve = app.CallingApp.Conductance(:,idx);
            end
            voltage = app.CallingApp.Voltage(:,idx);
            % Do the deal
            CurveSmooth = smoothDOS(voltage, curve, Threshold, Window,...
                Iterations);

            if app.DOSButton.Value
                app.CallingApp.DOSSmooth(:,idx) = CurveSmooth;
            else
                app.CallingApp.ConductanceSmooth(:,idx) = CurveSmooth;
            end

            delete(app)
        end

        % Close request function: UIFigure
        function SmoothDialogAppCloseRequest(app, event)
            if isvalid(app.CallingApp)
                app.CallingApp.SmoothButton.Enable = 'on';
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @SmoothDialogAppCloseRequest, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ThresholdmeVEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdmeVEditFieldLabel.Text = 'Threshold (meV)';
            app.ThresholdmeVEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdmeVEditField.Limits = [0 Inf];
            app.WindowEditFieldLabel = uilabel(app.UIFigure);
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowEditField.Limits = [1 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.Value = 1;
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.UpperLimitInclusive = 'off';
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.Value = 1;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.CondButton = uitogglebutton(app.ButtonGroup);
            app.CondButton.Text = 'Cond.';
            app.CondButton.Value = true;
            app.DOSButton = uitogglebutton(app.ButtonGroup);
            app.DOSButton.Text = 'DOS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmoothDialogBox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end